classdef testApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ColorSpinner         matlab.ui.control.Spinner
        ColorSpinnerLabel    matlab.ui.control.Label
        Button_2             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        ClearButton          matlab.ui.control.Button
        ClickPositionLabel   matlab.ui.control.Label
        NumberofSeedsLabel   matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        SizeSlider           matlab.ui.control.Slider
        SizeSliderLabel      matlab.ui.control.Label
        RotationSlider       matlab.ui.control.Slider
        RotationSliderLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
%     properties (Access = public)
%         cmap = hsv(10); % Description
%         color_count = 0;
%     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RotationSlider
        function RotationSliderValueChanged(app, event)
            value = app.RotationSlider.Value;
            app.Label.Text = string(value);
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
            
%             app.color_count = app.color_count + 1;
%             if(app.color_count == 10)
%                 app.color_count = 1;
%             end

            % get values from rotation and size sliders
            rotation = app.RotationSlider.Value;
            size = app.SizeSlider.Value;
            
            % retrieve and display x and y coordinate of click 
            coords = event.IntersectionPoint;
            app.ClickPositionLabel.Text  = string(coords(1) + ",  " + coords(2));
           
            %make color map based on spinner value
            cmap = hsv(app.ColorSpinner.Value);

            % set up variables
            r = [0];
            theta = [0];
            color_array = [0 0 0];
            count = 0;
            r_inc = 0;

            % color array created
            color_count = 1;
            color_array(1,:) = cmap(color_count, :);


            % each loop adds a new point and expands and grows previous points 
            while max(r) < size
                % increase count
                count = count + 1; 
                % increase radius array and add new value with x_pos and y_pos 
                r_inc = r_inc + 0.001;
                r = r + r_inc;
                r(end+1) = 0;
                theta(end+1) = theta(end) + rotation*2*pi;
                x_pos = coords(1) + r.*cos(theta);
                y_pos = coords(2) + r.*sin(theta);

                % add new color to color_array
                color_count = color_count + 1;
                if(color_count > app.ColorSpinner.Value || color_count > 10)
                    color_count = 1;
                end

                color_array(end+1, :) = cmap(color_count, :);

                for a = 1:length(x_pos)
                    
                    % plot(app.UIAxes,x_pos(a),y_pos(a), 'o','MarkerSize',r(a)); %, 'Color', app.cmap(app.color_count, :));
                    plot(app.UIAxes,x_pos(a),y_pos(a), 'o', 'MarkerSize', r(a)*5 + 4, 'Color', color_array(a, :));
                    hold(app.UIAxes, "on");
                    
                end
%                   plot(app.UIAxes,x_pos,y_pos, 'o','MarkerSize', 12, 'Color', app.cmap(app.color_count, :));
                hold(app.UIAxes, "off");
                app.Label_3.Text = string(count);
                pause(0.01)

            end 


            %hold(app.UIAxes, "on");

        end

        % Value changed function: SizeSlider
        function SizeSliderValueChanged(app, event)
            value = app.SizeSlider.Value;
            app.Label_2.Text = string(value);            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.RotationSlider.Value = (137.507/360);
            app.Label.Text = string((137.507/360));
            %app.RotationSlider('Position',[50 50 150 3]);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.RotationSlider.Value = 1 - (137.507/360);
            app.Label.Text = string(1-(137.507/360));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 20];
            app.UIAxes.ZLim = [0 20];
            app.UIAxes.XTick = [0 5 10 15 20];
            app.UIAxes.YTick = [0 5 10 15 20];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RotationSliderLabel = uilabel(app.UIFigure);
            app.RotationSliderLabel.Text = 'Rotation';
            app.RotationSlider = uislider(app.UIFigure);
            app.RotationSlider.Limits = [0 1];
            app.RotationSlider.ValueChangedFcn = createCallbackFcn(app, @RotationSliderValueChanged, true);
            app.SizeSliderLabel = uilabel(app.UIFigure);
            app.SizeSliderLabel.Text = 'Size';
            app.SizeSlider = uislider(app.UIFigure);
            app.SizeSlider.Limits = [0 3];
            app.SizeSlider.ValueChangedFcn = createCallbackFcn(app, @SizeSliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0.0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0.0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.NumberofSeedsLabel = uilabel(app.UIFigure);
            app.NumberofSeedsLabel.Text = 'Number of Seeds:';
            app.ClickPositionLabel = uilabel(app.UIFigure);
            app.ClickPositionLabel.Text = 'Click Position';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'φ';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '1- φ';
            app.ColorSpinnerLabel = uilabel(app.UIFigure);
            app.ColorSpinnerLabel.Text = 'Color';
            app.ColorSpinner = uispinner(app.UIFigure);
            app.ColorSpinner.Limits = [1 15];
            app.ColorSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end